classdef mainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        LaunchTab               matlab.ui.container.Tab
        LetsbookyourtrainwithjustatapLabel  matlab.ui.control.Label
        BuyTicketsButton        matlab.ui.control.Button
        Image3                  matlab.ui.control.Image
        Image2                  matlab.ui.control.Image
        UserTab                 matlab.ui.container.Tab
        ButtonGroup             matlab.ui.container.ButtonGroup
        Button3                 matlab.ui.control.RadioButton
        Button2                 matlab.ui.control.RadioButton
        Button                  matlab.ui.control.RadioButton
        QueryTab                matlab.ui.container.Tab
        SearchButton            matlab.ui.control.Button
        SelectDestinationPanel  matlab.ui.container.Panel
        ToStationDropDown       matlab.ui.control.DropDown
        FromDropDownLabel_2     matlab.ui.control.Label
        StartStationDropDown    matlab.ui.control.DropDown
        FromDropDownLabel       matlab.ui.control.Label
        Image4                  matlab.ui.control.Image
    end

    
    properties (Access = public)
        trainDispatch % Description
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BuyTicketsButton
        function BuyTicketsButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.QueryTab;
        end

        % Callback function
        function DebugButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.debugTab;
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            startStation=Station(app.StartStationDropDown.Value);
            startStation.arrivalTime=app.trainDispatch.SysTime;
            app.trainDispatch.findAvailableTickets(startStation,Station(app.ToStationDropDown.Value),[]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LaunchTab = uitab(app.TabGroup);
            app.LaunchTab.Title = '初始界面';
            app.Image2 = uiimage(app.LaunchTab);
            app.Image2.ImageSource = 'launch-bg-norad.png';
            app.Image3 = uiimage(app.LaunchTab);
            app.Image3.ImageSource = 'launch-bg-gradient-black.png';
            app.BuyTicketsButton = uibutton(app.LaunchTab, 'push');
            app.BuyTicketsButton.ButtonPushedFcn = createCallbackFcn(app, @BuyTicketsButtonPushed, true);
            app.BuyTicketsButton.Text = 'Buy  Tickets';
            app.LetsbookyourtrainwithjustatapLabel = uilabel(app.LaunchTab);
            app.LetsbookyourtrainwithjustatapLabel.Text = {'Let''s book your train'; 'with just a tap'; ''};
            app.UserTab = uitab(app.TabGroup);
            app.UserTab.Title = '用户界面';
            app.ButtonGroup = uibuttongroup(app.UserTab);
            app.ButtonGroup.Title = 'Button Group';
            app.Button = uiradiobutton(app.ButtonGroup);
            app.Button.Text = 'Button';
            app.Button.Value = true;
            app.Button2 = uiradiobutton(app.ButtonGroup);
            app.Button2.Text = 'Button2';
            app.Button3 = uiradiobutton(app.ButtonGroup);
            app.Button3.Text = 'Button3';
            app.QueryTab = uitab(app.TabGroup);
            app.QueryTab.Title = '查询界面';
            app.Image4 = uiimage(app.QueryTab);
            app.Image4.ImageSource = 'query-bg1.png';
            app.SelectDestinationPanel = uipanel(app.QueryTab);
            app.SelectDestinationPanel.Title = 'Select Destination';
            app.FromDropDownLabel = uilabel(app.SelectDestinationPanel);
            app.FromDropDownLabel.Text = 'From';
            app.StartStationDropDown = uidropdown(app.SelectDestinationPanel);
            app.StartStationDropDown.Items = {'南京南', '常州北', '苏州北', '上海虹桥', '嘉兴南', '溧阳', '湖州', '杭州东'};
            app.StartStationDropDown.Value = '南京南';
            app.FromDropDownLabel_2 = uilabel(app.SelectDestinationPanel);
            app.FromDropDownLabel_2.Text = 'To';
            app.ToStationDropDown = uidropdown(app.SelectDestinationPanel);
            app.ToStationDropDown.Items = {'南京南', '常州北', '苏州北', '上海虹桥', '嘉兴南', '溧阳', '湖州', '杭州东'};
            app.ToStationDropDown.Value = '溧阳';
            app.SearchButton = uibutton(app.QueryTab, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainApp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end